classdef fungen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Graph                    matlab.ui.control.UIAxes
        AmplitudeEditFieldLabel  matlab.ui.control.Label
        Amplitude                matlab.ui.control.NumericEditField
        DCOffsetLabel            matlab.ui.control.Label
        DC                       matlab.ui.control.NumericEditField
        FrequencyKnobLabel       matlab.ui.control.Label
        FrequencyKnob            matlab.ui.control.Knob
        NSliderLabel             matlab.ui.control.Label
        NSlider                  matlab.ui.control.Slider
        FunctionKnobLabel        matlab.ui.control.Label
        FunctionKnob             matlab.ui.control.DiscreteKnob
        SoundButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        autoUpdate
    end
    
    methods (Access = private)
        
        function updatePlot(app)
            value = app.FunctionKnob.Value;
            n=app.NSlider.Value;
            dc=app.DC.Value;
            a=app.Amplitude.Value;
            f=app.FrequencyKnob.Value;
            w0=2.*pi.*f;
            maxBufferSize = 8192;
            maxrate = 44100;
            pointsPerPeriod = maxrate / f;
            pointsPerPeriod = pow2(floor(log(pointsPerPeriod) / log(2.0)));
            if (pointsPerPeriod > maxBufferSize)
                pointsPerPeriod = maxBufferSize;
            end
            
            if (pointsPerPeriod < 2)
                pointsPerPeriod = 2;
            end
            
            t = linspace(0,10,w0.*pointsPerPeriod);
            y = zeros(1,length(t));
            [y] = generateGraph(app);
            plot(app.Graph,t,y);
            app.Graph.XLim=[0 4./f];


        end
        
        function y = generateGraph(app)
            value = app.FunctionKnob.Value;
            n=app.NSlider.Value;
            dc=app.DC.Value;
            a=app.Amplitude.Value;
            f=app.FrequencyKnob.Value;
            w0=2.*pi.*f;
            maxBufferSize = 8192;
            maxrate = 44100;
            pointsPerPeriod = maxrate / f;
            pointsPerPeriod = pow2(floor(log(pointsPerPeriod) / log(2.0)));
            if (pointsPerPeriod > maxBufferSize)
                pointsPerPeriod = maxBufferSize;
            end
            
            if (pointsPerPeriod < 2)
                pointsPerPeriod = 2;
            end
            
            t = linspace(0,10,w0.*pointsPerPeriod);
            y = zeros(1,length(t));
            switch app.FunctionKnob.Value
                case 'Sine'
                    y = sin(w0.*t);
                    y = y .* a;
                    y = y + dc;
                case 'Square'
                    for i = 1:2:n
                        an = 4/((i.*pi));
                        y =  y + an*sin(w0.*t.*i);
                    end
                    y = y .* a;
                    y = y + dc;
                case 'Triangle'
                    for i = 1:2:n
                        an = 0.5 - (4./pi.^2);
                        y =  y + (1./i.^2).*an*cos(w0.*i.*t);
                    end
                    y = y .* a;
                    y = y + dc;
                case 'Sawtooth'
                    for i = 1:n
                        an = 0.5 - (1./pi);
                        y =  y + (1./i).*an*sin(w0.*i.*t);
                    end
                    y = y .* a;
                    y = y + dc;
                case 'Rectified'
                    for i = 1:n
                        an = 2./pi - 4./pi;
                        y =  y + (1./(4.*i.^2-1)).*an*cos(w0.*i.*t);
                        
                    end
                    y = y .* a;
                    y = y + dc;
                case 'Cosine'
                    y=cos(w0.*t);
                    y = y .* a;
                    y = y + dc;
                case'Default'
                    plot(app.Graph,0,0);
                    
            end
        end
        
        function Bang(app)
            f=app.FrequencyKnob.Value;
            w0=2.*pi.*f;
            maxBufferSize = 8192;
            maxrate = 44100;
            pointsPerPeriod = maxrate / f;
            pointsPerPeriod = pow2(floor(log(pointsPerPeriod) / log(2.0)));
            if (pointsPerPeriod > maxBufferSize)
                pointsPerPeriod = maxBufferSize;
            end
            
            if (pointsPerPeriod < 2)
                pointsPerPeriod = 2;
            end
            
            t = linspace(0,10,w0.*pointsPerPeriod);
            [y] = generateGraph(app);
            sound(y,length(y)./max(t))
            
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.autoUpdate=1;
            updatePlot(app);
        end

        % Value changed function: FunctionKnob
        function FunctionKnobValueChanged(app, event)
            value = app.FunctionKnob.Value;
            updatePlot(app);
        end

        % Value changed function: NSlider
        function NSliderValueChanged(app, event)
            n = app.NSlider.Value;
            updatePlot(app);
        end

        % Value changed function: FrequencyKnob
        function FrequencyKnobValueChanged(app, event)
            f = app.FrequencyKnob.Value;
            updatePlot(app);
        end

        % Value changed function: Amplitude
        function AmplitudeValueChanged(app, event)
            a = app.Amplitude.Value;
            updatePlot(app);
        end

        % Value changed function: DC
        function DCValueChanged(app, event)
            dc = app.DC.Value;
            updatePlot(app);
        end

        % Button pushed function: SoundButton
        function SoundButtonPushed(app, event)
            Bang(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Graph = uiaxes(app.UIFigure);
            title(app.Graph, 'Function Generator')
            xlabel(app.Graph, 'Time')
            ylabel(app.Graph, 'Y')
            app.AmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.Amplitude = uieditfield(app.UIFigure, 'numeric');
            app.Amplitude.Limits = [1 Inf];
            app.Amplitude.ValueChangedFcn = createCallbackFcn(app, @AmplitudeValueChanged, true);
            app.Amplitude.Value = 1;
            app.DCOffsetLabel = uilabel(app.UIFigure);
            app.DCOffsetLabel.Text = 'DC Offset';
            app.DC = uieditfield(app.UIFigure, 'numeric');
            app.DC.ValueChangedFcn = createCallbackFcn(app, @DCValueChanged, true);
            app.FrequencyKnobLabel = uilabel(app.UIFigure);
            app.FrequencyKnobLabel.Text = 'Frequency';
            app.FrequencyKnob = uiknob(app.UIFigure, 'continuous');
            app.FrequencyKnob.Limits = [0 6000];
            app.FrequencyKnob.ValueChangedFcn = createCallbackFcn(app, @FrequencyKnobValueChanged, true);
            app.FrequencyKnob.Value = 10;
            app.NSliderLabel = uilabel(app.UIFigure);
            app.NSliderLabel.Text = 'N';
            app.NSlider = uislider(app.UIFigure);
            app.NSlider.Limits = [0 500];
            app.NSlider.ValueChangedFcn = createCallbackFcn(app, @NSliderValueChanged, true);
            app.NSlider.Value = 100;
            app.FunctionKnobLabel = uilabel(app.UIFigure);
            app.FunctionKnobLabel.Text = 'Function';
            app.FunctionKnob = uiknob(app.UIFigure, 'discrete');
            app.FunctionKnob.Items = {'Default', 'Sine', 'Cosine', 'Square', 'Triangle', 'Sawtooth', 'Rectified'};
            app.FunctionKnob.ValueChangedFcn = createCallbackFcn(app, @FunctionKnobValueChanged, true);
            app.FunctionKnob.Value = 'Default';
            app.SoundButton = uibutton(app.UIFigure, 'push');
            app.SoundButton.ButtonPushedFcn = createCallbackFcn(app, @SoundButtonPushed, true);
            app.SoundButton.Text = 'Sound';
        end
    end

    methods (Access = public)

        % Construct app
        function app = fungen

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end